classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        MainMenu                       matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        RedDropDownLabel               matlab.ui.control.Label
        RedDropDown                    matlab.ui.control.DropDown
        IdentificarButton              matlab.ui.control.Button
        ClasificacinButton             matlab.ui.control.Button
        EntrenamientoButton            matlab.ui.control.Button
        Help                           matlab.ui.container.Panel
        Back                           matlab.ui.control.Button
        HTML2                          matlab.ui.control.HTML
        TrainingMenu                   matlab.ui.container.Panel
        OpcionesdevisualizacinLabel    matlab.ui.control.Label
        OpcionesdeentrenamientoLabel   matlab.ui.control.Label
        ShowAugImages                  matlab.ui.control.CheckBox
        ShowNetModels                  matlab.ui.control.CheckBox
        AnalyzeModifiedNet             matlab.ui.control.CheckBox
        AnalyzeFirstNet                matlab.ui.control.CheckBox
        ShowTrainImages                matlab.ui.control.CheckBox
        TasadeaprendizajeinicialEditField  matlab.ui.control.NumericEditField
        TasadeaprendizajeinicialEditFieldLabel  matlab.ui.control.Label
        NmerodepocasEditField          matlab.ui.control.NumericEditField
        NmerodepocasEditFieldLabel     matlab.ui.control.Label
        TamaodeminiloteEditField       matlab.ui.control.NumericEditField
        TamaodeminiloteEditFieldLabel  matlab.ui.control.Label
        SolverDropDown                 matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        VolverButton                   matlab.ui.control.Button
        EntrenarButton                 matlab.ui.control.Button
        SearchPanel                    matlab.ui.container.Panel
        MsinformacinLabel              matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        VolverButton_2                 matlab.ui.control.Button
        HTML                           matlab.ui.control.HTML
        ImageResult                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        ModelExists = false; % Cambia en función de si el modelo seleccionado ya esta entrenado
        SelectedModel = "";
        ModelToLoad = "";
        ImdsValidation = "\imdsValidation.mat";
        AugImdsValidation = "\augimdsValidation.mat";
        ImdsTrain = "\imdsTrain.mat";
        AugImdsTrain = "\augimdsTrain.mat";
        Size = 0;
    end

    methods (Access = private)
        
        function checkModel(app)
            value = lower(erase(app.RedDropDown.Value, " "));
            app.SelectedModel = value;
            switch app.SelectedModel
                case {"googlenet"}
                    app.Size = 224;
                case "inceptionv3"
                    app.Size = 299;
                case {"alexnet", "squeezenet"}
                    app.Size = 227;
            end
            app.ModelToLoad = "\netTransferMonumentos" +value + ".mat";
            model = "..\ModelosEntrenados\" + value + "\netTransferMonumentos" +value + ".mat";
            disp(model);
            if exist(model,"file")
                 app.ModelExists = true;
                 app.ClasificacinButton.Enable = "on";
                 app.IdentificarButton.Enable = "on";
            else
                 app.ModelExists = false;
                 app.EntrenamientoButton.Enable = "on";
                 app.ClasificacinButton.Enable = "off";
                 app.IdentificarButton.Enable = "off";
            end
        end
        
        function goToMainMenu(app)
            app.MainMenu.Visible = 'on';
            app.TrainingMenu.Visible = 'off';
            app.SearchPanel.Visible = 'off';
            app.Help.Visible = 'off';
        end
        function showPanel(app, panel)
            app.SearchPanel.Visible = 'off';
            app.Help.Visible = 'off';
            app.MainMenu.Visible = 'off';
            app.TrainingMenu.Visible = 'off';
            panel.Visible = 'on';
        end

        function disableButtons(app)
            app.RedDropDown.Enable = "off";
            app.IdentificarButton.Enable = "off";
            app.ClasificacinButton.Enable = "off";
            app.EntrenamientoButton.Enable = "off";
            app.VolverButton.Enable = "off";
            app.EntrenarButton.Enable = "off";
            app.VolverButton_2.Enable = "off";
            app.Back.Enable = "off";
        end

        function enableButtons(app)
            app.RedDropDown.Enable = "on";
            app.IdentificarButton.Enable = "on";
            app.ClasificacinButton.Enable = "on";
            app.EntrenamientoButton.Enable = "on";
            app.VolverButton.Enable = "on";
            app.EntrenarButton.Enable = "on";
            app.VolverButton_2.Enable = "on";
            app.Back.Enable = "on";
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(mfilename("fullpath")) + "\src");
            app.HTML2.HTMLSource = ".\html\pagina_ayuda.html";
            checkModel(app);
        end

        % Value changed function: RedDropDown
        function RedDropDownValueChanged(app, event)
            checkModel(app);
        end

        % Button pushed function: EntrenamientoButton
        function EntrenamientoButtonPushed(app, event)
            if app.ModelExists == true
                choice = questdlg('El modelo seleccionado ya ha sido entrenado, si continúa con el proceso se sustituirá. ¿Está seguro?', 'Confirmación', 'Sí', 'No', 'No');
                switch choice
                    case "Sí"                        
                        showPanel(app, app.TrainingMenu);
                    case "No"
                end
            else
                showPanel(app,app.TrainingMenu);
            end
        end

        % Button pushed function: Back, VolverButton, VolverButton_2
        function VolverButtonPushed(app, event)
            goToMainMenu(app);
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            switch app.SelectedModel
                case {"googlenet"}
                    disableButtons(app);
                    Training224(app.SelectedModel,app.SolverDropDown.Value, app.TamaodeminiloteEditField.Value, app.NmerodepocasEditField.Value, app.TasadeaprendizajeinicialEditField.Value, app.ShowTrainImages.Value, app.AnalyzeFirstNet.Value, app.AnalyzeModifiedNet.Value, app.ShowNetModels.Value, app.ShowAugImages.Value);
                    enableButtons(app)
                    goToMainMenu(app);
                case "inceptionv3"
                    disableButtons(app);
                    Training299(app.SelectedModel,app.SolverDropDown.Value, app.TamaodeminiloteEditField.Value, app.NmerodepocasEditField.Value, app.TasadeaprendizajeinicialEditField.Value, app.ShowTrainImages.Value, app.AnalyzeFirstNet.Value, app.AnalyzeModifiedNet.Value, app.ShowNetModels.Value, app.ShowAugImages.Value);
                    enableButtons(app)
                    goToMainMenu(app);
                case {"alexnet", "squeezenet"}
                    disableButtons(app);
                    Training227(app.SelectedModel,app.SolverDropDown.Value, app.TamaodeminiloteEditField.Value, app.NmerodepocasEditField.Value, app.TasadeaprendizajeinicialEditField.Value, app.ShowTrainImages.Value, app.AnalyzeFirstNet.Value, app.AnalyzeModifiedNet.Value, app.ShowNetModels.Value, app.ShowAugImages.Value);
                    enableButtons(app)
                    goToMainMenu(app);
            end
        end

        % Button pushed function: ClasificacinButton
        function ClasificacinButtonPushed(app, event)
            disableButtons(app);
            Classify(app.SelectedModel, app.ModelToLoad, app.ImdsValidation, app.AugImdsValidation, app.ImdsTrain, app.AugImdsTrain);
            enableButtons(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            respuesta = uiconfirm(app.UIFigure, '¿Estás seguro de que quieres salir?', 'Confirmar cierre', 'Options', {'Sí', 'Cancelar'}, 'DefaultOption', 'Cancelar');
            switch respuesta
                case 'Sí'
                    delete(app);
                case 'Cancelar'   
            end
        end

        % Button pushed function: IdentificarButton
        function IdentificarButtonPushed(app, event)
            % Abre el cuadro de diálogo para seleccionar un archivo
            [archivo, ruta] = uigetfile({'*.jpg'; '*.png'}, 'Seleccionar un archivo');
            rutaArchivo = fullfile(ruta, archivo);
            
            % Verifica si el usuario seleccionó un archivo
            if archivo ~= 0
                disableButtons(app);
                [modelo, imagen] = Search(app.SelectedModel, app.ModelToLoad, rutaArchivo, app.Size);
                showPanel(app, app.SearchPanel);
                app.ImageResult.ImageSource = imagen;
                switch modelo
                    case "Felipe III"
                        app.HTML.HTMLSource = ".\html\felipeiii.html";
                        app.Hyperlink.URL = "https://es.wikipedia.org/wiki/Estatua_ecuestre_de_Felipe_III";
                    case "Fuente de Cibeles"
                        app.HTML.HTMLSource = ".\html\cibeles.html";
                        app.Hyperlink.URL = "https://es.wikipedia.org/wiki/Fuente_de_Cibeles";
                    case "Neptuno"
                        app.HTML.HTMLSource = ".\html\neptuno.html";
                        app.Hyperlink.URL = "https://es.wikipedia.org/wiki/Fuente_de_Neptuno_(Madrid)";
                    case "Oso y el Madroño"
                        app.HTML.HTMLSource = ".\html\osomadroño.html";
                        app.Hyperlink.URL = "https://es.wikipedia.org/wiki/Estatua_del_Oso_y_el_Madro%C3%B1o";
                    case "Puerta de Alcalá"
                        app.HTML.HTMLSource = ".\html\alcala.html";
                        app.Hyperlink.URL = "https://es.wikipedia.org/wiki/Puerta_de_Alcal%C3%A1";
                    case "Puerta de Toledo"
                        app.HTML.HTMLSource = ".\html\toledo.html";
                        app.Hyperlink.URL = "https://es.wikipedia.org/wiki/Puerta_de_Toledo_(Madrid)";
                end
                enableButtons(app);
            end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            showPanel(app, app.Help);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SearchPanel = uipanel(app.UIFigure);
            app.SearchPanel.Visible = 'off';
            app.ImageResult = uiimage(app.SearchPanel);
            app.ImageResult.ImageSource = fullfile(pathToMLAPP, 'DATASET299x299', 'Oso y el Madroño', '2oso.jpg');
            app.HTML = uihtml(app.SearchPanel);
            app.VolverButton_2 = uibutton(app.SearchPanel, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton_2.Text = 'Volver';
            app.Hyperlink = uihyperlink(app.SearchPanel);
            app.Hyperlink.URL = 'https://es.wikipedia.org/wiki/Estatua_del_Oso_y_el_Madro%C3%B1o';
            app.Hyperlink.Text = 'Wikipedia';
            app.MsinformacinLabel = uilabel(app.SearchPanel);
            app.MsinformacinLabel.Text = 'Más información:';
            app.TrainingMenu = uipanel(app.UIFigure);
            app.TrainingMenu.Visible = 'off';
            app.EntrenarButton = uibutton(app.TrainingMenu, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.VolverButton = uibutton(app.TrainingMenu, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.SolverDropDownLabel = uilabel(app.TrainingMenu);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.TrainingMenu);
            app.SolverDropDown.Items = {'sgdm', 'adam', 'rmsprop', 'lbfgs'};
            app.SolverDropDown.Value = 'sgdm';
            app.TamaodeminiloteEditFieldLabel = uilabel(app.TrainingMenu);
            app.TamaodeminiloteEditFieldLabel.Text = 'Tamaño de minilote';
            app.TamaodeminiloteEditField = uieditfield(app.TrainingMenu, 'numeric');
            app.TamaodeminiloteEditField.Limits = [4 500];
            app.TamaodeminiloteEditField.RoundFractionalValues = 'on';
            app.TamaodeminiloteEditField.Value = 10;
            app.NmerodepocasEditFieldLabel = uilabel(app.TrainingMenu);
            app.NmerodepocasEditFieldLabel.Text = 'Número de épocas';
            app.NmerodepocasEditField = uieditfield(app.TrainingMenu, 'numeric');
            app.NmerodepocasEditField.Limits = [1 50];
            app.NmerodepocasEditField.RoundFractionalValues = 'on';
            app.NmerodepocasEditField.Value = 6;
            app.TasadeaprendizajeinicialEditFieldLabel = uilabel(app.TrainingMenu);
            app.TasadeaprendizajeinicialEditFieldLabel.Text = 'Tasa de aprendizaje  inicial';
            app.TasadeaprendizajeinicialEditField = uieditfield(app.TrainingMenu, 'numeric');
            app.TasadeaprendizajeinicialEditField.Limits = [0.0001 0.05];
            app.TasadeaprendizajeinicialEditField.Value = 0.0003;
            app.ShowTrainImages = uicheckbox(app.TrainingMenu);
            app.ShowTrainImages.Text = 'Mostrar ejemplos de imágenes escogidas.';
            app.ShowTrainImages.Value = true;
            app.AnalyzeFirstNet = uicheckbox(app.TrainingMenu);
            app.AnalyzeFirstNet.Text = 'Mostrar análisis de la red inicial.';
            app.AnalyzeFirstNet.Value = true;
            app.AnalyzeModifiedNet = uicheckbox(app.TrainingMenu);
            app.AnalyzeModifiedNet.Text = 'Mostrar análisis de la red modificada.';
            app.AnalyzeModifiedNet.Value = true;
            app.ShowNetModels = uicheckbox(app.TrainingMenu);
            app.ShowNetModels.Text = 'Mostrar gráfica de los modelos de red.';
            app.ShowNetModels.Value = true;
            app.ShowAugImages = uicheckbox(app.TrainingMenu);
            app.ShowAugImages.Text = 'Mostrar ejemplos de imágenes aumentadas.';
            app.ShowAugImages.Value = true;
            app.OpcionesdeentrenamientoLabel = uilabel(app.TrainingMenu);
            app.OpcionesdeentrenamientoLabel.Text = 'Opciones de entrenamiento';
            app.OpcionesdevisualizacinLabel = uilabel(app.TrainingMenu);
            app.OpcionesdevisualizacinLabel.Text = 'Opciones de visualización';
            app.Help = uipanel(app.UIFigure);
            app.Help.Visible = 'off';
            app.HTML2 = uihtml(app.Help);
            app.Back = uibutton(app.Help, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.Back.Text = 'Volver';
            app.MainMenu = uipanel(app.UIFigure);
            app.EntrenamientoButton = uibutton(app.MainMenu, 'push');
            app.EntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenamientoButtonPushed, true);
            app.EntrenamientoButton.Text = 'Entrenamiento';
            app.ClasificacinButton = uibutton(app.MainMenu, 'push');
            app.ClasificacinButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificacinButtonPushed, true);
            app.ClasificacinButton.Enable = 'off';
            app.ClasificacinButton.Text = 'Clasificación';
            app.IdentificarButton = uibutton(app.MainMenu, 'push');
            app.IdentificarButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificarButtonPushed, true);
            app.IdentificarButton.Enable = 'off';
            app.IdentificarButton.Text = 'Identificar';
            app.RedDropDown = uidropdown(app.MainMenu);
            app.RedDropDown.Items = {'Inception v3', 'AlexNet', 'SqueezeNet', 'GoogLeNet'};
            app.RedDropDown.ValueChangedFcn = createCallbackFcn(app, @RedDropDownValueChanged, true);
            app.RedDropDown.Value = 'Inception v3';
            app.RedDropDownLabel = uilabel(app.MainMenu);
            app.RedDropDownLabel.Text = 'Red:';
            app.Image2 = uiimage(app.MainMenu);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Recursos', 'help.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Recursos', 'image.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end